classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        WprowadzaniedanychTab           matlab.ui.container.Tab
        NowylekEditField                matlab.ui.control.EditField
        NowylekEditFieldLabel           matlab.ui.control.Label
        NazwalekuDropDown               matlab.ui.control.DropDown
        NazwalekuDropDownLabel          matlab.ui.control.Label
        DodajlekButton                  matlab.ui.control.Button
        IloprzyjtegolekuEditField       matlab.ui.control.NumericEditField
        IloprzyjtegolekuEditFieldLabel  matlab.ui.control.Label
        PrzyjtelekiLabel                matlab.ui.control.Label
        OwulacjaCheckBox                matlab.ui.control.CheckBox
        DodajButton                     matlab.ui.control.Button
        WkadkiEditField                 matlab.ui.control.NumericEditField
        WkadkiEditFieldLabel            matlab.ui.control.Label
        TamponyEditField                matlab.ui.control.NumericEditField
        TamponyEditFieldLabel           matlab.ui.control.Label
        PodpaskiEditField               matlab.ui.control.NumericEditField
        PodpaskiEditFieldLabel          matlab.ui.control.Label
        IntensywnokrwawieniaSlider      matlab.ui.control.Slider
        IntensywnokrwawieniaSliderLabel  matlab.ui.control.Label
        ZuycierodkwhigienicznychLabel   matlab.ui.control.Label
        KoniecmiesiczkiCheckBox         matlab.ui.control.CheckBox
        PocztekmiesiczkiCheckBox        matlab.ui.control.CheckBox
        DataDatePicker                  matlab.ui.control.DatePicker
        DataDatePickerLabel             matlab.ui.control.Label
        ObjawyTab                       matlab.ui.container.Tab
        DodajButton_2                   matlab.ui.control.Button
        SennoCheckBox                   matlab.ui.control.CheckBox
        NadmiernyapetytCheckBox         matlab.ui.control.CheckBox
        BrakapetytuCheckBox             matlab.ui.control.CheckBox
        SpadeklibidoCheckBox_2          matlab.ui.control.CheckBox
        SpadeksamoocenyCheckBox_2       matlab.ui.control.CheckBox
        ProblemyzkoncentracjCheckBox_2  matlab.ui.control.CheckBox
        ZmczenieCheckBox                matlab.ui.control.CheckBox
        WahanianastrojuCheckBox         matlab.ui.control.CheckBox
        SmutekCheckBox_2                matlab.ui.control.CheckBox
        RozdranienieCheckBox_2          matlab.ui.control.CheckBox
        ZmianyskrneCheckBox_2           matlab.ui.control.CheckBox
        PowikszenieitkliwopiersiCheckBox_2  matlab.ui.control.CheckBox
        BlepodbrzuszaCheckBox           matlab.ui.control.CheckBox
        WzdciaCheckBox_2                matlab.ui.control.CheckBox
        ZaparciaCheckBox_2              matlab.ui.control.CheckBox
        OBJAWYLabel                     matlab.ui.control.Label
        BiegunkiCheckBox_2              matlab.ui.control.CheckBox
        BlewkrzyuCheckBox_2             matlab.ui.control.CheckBox
        BlegowyCheckBox_2               matlab.ui.control.CheckBox
        CyklTab                         matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        redniadugocykluLabel            matlab.ui.control.Label
        redniadugookresuLabel           matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        RokDropDown                     matlab.ui.control.DropDown
        RokDropDownLabel                matlab.ui.control.Label
        MiesicDropDown                  matlab.ui.control.DropDown
        MiesicDropDownLabel             matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        rodkihigieniczneTab             matlab.ui.container.Tab
        OKButton_2                      matlab.ui.control.Button
        RokDropDown_2                   matlab.ui.control.DropDown
        RokDropDown_2Label              matlab.ui.control.Label
        MiesicDropDown_2                matlab.ui.control.DropDown
        MiesicDropDown_2Label           matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        LekiTab                         matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        RokDropDown_3                   matlab.ui.control.DropDown
        RokDropDown_3Label              matlab.ui.control.Label
        MiesicDropDown_3                matlab.ui.control.DropDown
        MiesicDropDown_3Label           matlab.ui.control.Label
        OKButton_3                      matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ObjawyTab_2                     matlab.ui.container.Tab
        UITable3                        matlab.ui.control.Table
        DolegliwoDropDown               matlab.ui.control.DropDown
        DolegliwoDropDownLabel          matlab.ui.control.Label
        OKButton_4                      matlab.ui.control.Button
        RokDropDown_4                   matlab.ui.control.DropDown
        RokDropDown_4Label              matlab.ui.control.Label
        MiesicDropDown_4                matlab.ui.control.DropDown
        MiesicDropDown_4Label           matlab.ui.control.Label
        UIAxes5                         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            oldData = readtable('leki.txt');
            n = oldData.addItems;
            app.NazwalekuDropDown.Items = n;
        end

        % Button pushed function: DodajButton
        function Dalej1(app, event)
            global data;
            data = datetime(app.DataDatePicker.Value);

            poczatek = app.PocztekmiesiczkiCheckBox.Value;
            koniec = app.KoniecmiesiczkiCheckBox.Value;
            owulacja = app.OwulacjaCheckBox.Value;
            krwawienie = app.IntensywnokrwawieniaSlider.Value;

            podpaski = app.PodpaskiEditField.Value;
            tampony = app.TamponyEditField.Value;
            wkladki = app.WkadkiEditField.Value;

            nazwa_leku = app.NazwalekuDropDown.Value;
            ilosc_leku = app.IloprzyjtegolekuEditField.Value;


            newRow1 = table(data, poczatek, koniec, owulacja, krwawienie);
            oldData1 = readtable('cykl.txt');
            newData1 = [oldData1; newRow1];
            newData11 = sortrows(newData1, 'data');
            writetable(newData11, 'cykl.txt');
            

            newRow2 = table(data, podpaski, tampony, wkladki);
            oldData2 = readtable('srodki_higieniczne.txt');
            newData2 = [oldData2; newRow2];
            newData21 = sortrows(newData2, 'data');
            writetable(newData21, 'srodki_higieniczne.txt');

            newRow3 = table(data, {nazwa_leku}, ilosc_leku);
            oldData2 = readtable('lek.txt');
            newData3 = [oldData2; newRow3];
            newData31 = sortrows(newData3, 'data');
            writetable(newData31, 'lek.txt');
            
        end

        % Value changed function: IntensywnokrwawieniaSlider
        function SliderInt(app, event)
            value = round(app.IntensywnokrwawieniaSlider.Value);
            app.IntensywnokrwawieniaSlider.Value = value;
        end

        % Button pushed function: DodajlekButton
        function DodajLek(app, event)
            lek = app.NowylekEditField.Value;
            leki = ((length(app.NazwalekuDropDown.Items)) + 1);
            addItems = cell(leki, 1);
            for i = 1 : 1 : leki
                if i <= (length(app.NazwalekuDropDown.Items))
                    addItems{i} = char(app.NazwalekuDropDown.Items(i));
                else
                    addItems{i} = lek;
                end
            end
            app.NazwalekuDropDown.Items = addItems;
           
            newData5 = table(addItems);
            writetable(newData5, 'leki.txt');
        end

        % Callback function
        function Okres(app, event)
            value = app.OkresDropDown.Value;
             if value == 'rok'
                 set(app.RokDropDown,'Enable','on');
                 set(app.MiesicDropDown,'Enable','off');
             end
        end

        % Button pushed function: DodajButton_2
        function Dodaj2(app, event)
            global data
            data = datetime(app.DataDatePicker.Value);

            bolBrzucha = app.BlepodbrzuszaCheckBox.Value;
            bolGlowy = app.BlegowyCheckBox_2.Value;
            bolwKrzyzu = app.BlewkrzyuCheckBox_2.Value;
            biegunka = app.BiegunkiCheckBox_2.Value;
            zaparcia = app.ZaparciaCheckBox_2.Value;
            wzdecia = app.WzdciaCheckBox_2.Value;
            bolPiersi = app.PowikszenieitkliwopiersiCheckBox_2.Value;
            zmianySkorne = app.ZmianyskrneCheckBox_2.Value;
            rozdraznienie = app.RozdranienieCheckBox_2.Value;
            smutek = app.SmutekCheckBox_2.Value;
            wahaniaNastroju = app.WahanianastrojuCheckBox.Value;
            zmeczenie = app.ZmczenieCheckBox.Value;
            koncentracja = app.ProblemyzkoncentracjCheckBox_2.Value;
            samoocena = app.SpadeksamoocenyCheckBox_2.Value;
            spadekLibido = app.SpadeklibidoCheckBox_2.Value;
            brakApetytu = app.BrakapetytuCheckBox.Value;
            nadmiernyApetyt = app.NadmiernyapetytCheckBox.Value;
            sennosc = app.SennoCheckBox.Value;

            newRow = table(data, bolBrzucha, bolGlowy, bolwKrzyzu, biegunka, zaparcia, wzdecia, bolPiersi, zmianySkorne, rozdraznienie, smutek, wahaniaNastroju, zmeczenie, koncentracja, samoocena, spadekLibido, brakApetytu, nadmiernyApetyt, sennosc);
            oldData = readtable('objawy.txt');
            newData4 = [oldData; newRow];
            newData41 = sortrows(newData4, 'data');
            writetable(newData41, 'objawy.txt');

        end

        % Button pushed function: OKButton
        function OKcykl(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            mies = app.MiesicDropDown.Value;
            rok = app.RokDropDown.Value;
            if mies == '1'
                mies = 1;
            elseif mies == '2'
                mies = 2;
            elseif mies == '3'
                mies = 3;
            elseif mies == '4'
                mies = 4;
            elseif mies == '5'
                mies = 5;
            elseif mies == '6'
                mies = 6;
            elseif mies == '7'
                mies = 7;
            elseif mies == '8'
                mies = 8;
            elseif mies == '9'
                mies = 9;
            elseif mies == "10"
                mies = 10;
            elseif mies == "11"
                mies = 11;
            else mies == "12"
                mies = 12;
            end

            if rok == "2022"
                rok = 2022;
            elseif rok == "2021"
                rok = 2021;
            end


            cykl = readtable('cykl.txt');
            dzien = datetime(cykl.data);
            okres = cykl.krwawienie;
            x = length(cykl.data);
            for i = 1:1:x
                if cykl.data.Month(i, 1) == mies
                    if cykl.data.Year(i, 1) == rok
                        hold(app.UIAxes, 'on')
                        bar(app.UIAxes, dzien(i, 1), okres(i, 1), 'r')
                    end
                end
            end

            for i = 1:1:x
                if cykl.owulacja(i,:) == 1
                    if cykl.data.Month(i, 1) == mies
                        if cykl.data.Year(i, 1) == rok
                            plot(app.UIAxes, dzien(i, 1), 0, 'b*')
                        end
                    end
                end
            end
            hold(app.UIAxes, 'off')
            
            suma = 0;
            ilosc = 0;
            sumaRok = 0;
            for j = 1:1:12
                for i = 1:1:x
                    if cykl.data.Month(i, 1) == j
                        if cykl.data.Year(i, 1) == rok
                            suma = suma + cykl.krwawienie(i, 1);
                                if cykl.krwawienie(i, 1) ~= 0
                                    ilosc = ilosc + 1;
                                end
                        end
                    end
                end
                srednia = suma/ilosc;
                sumaRok = sumaRok + srednia;
                hold(app.UIAxes2, 'on')
                bar(app.UIAxes2, j, srednia, 'r')
                suma = 0;
                ilosc = 0;
            end
            sredniaRok = sumaRok/12;
            m = 1:1:12;
            plot(app.UIAxes2, m, sredniaRok, 'b*')
            hold(app.UIAxes2, 'off')


            sumaOkresow = 0;
            sumaDni = 0;
            for k = 1:1:x
                if cykl.data.Year(k, 1) == rok
                    for i = 1:1:365
                        if cykl.poczatek(i, 1) == 1
                            sumaOkresow = sumaOkresow + 1;
                            sumaDni = sumaDni + 2;
                            j = 1;
                            while cykl.koniec(j, 1) == 0
                                sumaDni = sumaDni + 1;
                                j = j + 1;  
                            end
                        end
                    end
                    break
                end
                break
            end
            srednia = round(sumaDni/sumaOkresow);
            text = string(srednia);
            app.Label.Text = text;

            cykl = readtable('cykl.txt');
            sumaOkresow1 = 0;
            x = length(cykl.data);
            for i = 1:1:x
                if cykl.poczatek(i, 1) == 1
                    sumaOkresow1 = sumaOkresow1 + 1;
                end
            end
            srednia1 = round(x/sumaOkresow1);
            text1 = string(srednia1);
            app.Label_2.Text = text1;
        end

        % Button pushed function: OKButton_2
        function HigOK(app, event)
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            mies = app.MiesicDropDown_2.Value;
            rok =app.RokDropDown_2.Value;

            if mies == '1'
                mies = 1;
            elseif mies == '2'
                mies = 2;
            elseif mies == '3'
                mies = 3;
            elseif mies == '4'
                mies = 4;
            elseif mies == '5'
                mies = 5;
            elseif mies == '6'
                mies = 6;
            elseif mies == '7'
                mies = 7;
            elseif mies == '8'
                mies = 8;
            elseif mies == '9'
                mies = 9;
            elseif mies == "10"
                mies = 10;
            elseif mies == "11"
                mies = 11;
            else mies == "12"
                mies = 12;
            end

            if rok == "2022"
                rok = 2022;
            elseif rok == "2021"
                rok = 2021;
            end

            hig = readtable('srodki_higieniczne.txt');

            data = hig.data;
            podpaski = hig.podpaski;
            tampony = hig.tampony;
            wkladki = hig.wkladki;
            x = length(data);
            podpaskiM = 0;
            tamponyM = 0;
            wkladkiM = 0;

            for i = 1:1:x
                if hig.data.Month(i, 1) == mies
                    if hig.data.Year(i, 1) == rok
                        podpaskiM = podpaskiM + podpaski(i, 1);
                        tamponyM = tamponyM + tampony(i, 1);
                        wkladkiM = wkladkiM + wkladki(i, 1);
                    end
                end
            end
            x = categorical({'podpaski', 'tampony', 'wkładki'});
            x = reordercats(x, {'podpaski', 'tampony', 'wkładki'});
            y = [podpaskiM, tamponyM, wkladkiM];
            bar(app.UIAxes3,x, y)

            podpaski1 = 0;
            tampony1 = 0;
            wkladki1 = 0;
            podpaski2 = 0;
            tampony2 = 0;
            wkladki2 = 0;
            podpaski3 = 0;
            tampony3 = 0;
            wkladki3 = 0;
            podpaski4 = 0;
            tampony4 = 0;
            wkladki4 = 0;
            podpaski5 = 0;
            tampony5 = 0;
            wkladki5 = 0;
            podpaski6 = 0;
            tampony6 = 0;
            wkladki6 = 0;
            podpaski7 = 0;
            tampony7 = 0;
            wkladki7 = 0;
            podpaski8 = 0;
            tampony8 = 0;
            wkladki8 = 0;
            podpaski9 = 0;
            tampony9 = 0;
            wkladki9 = 0;
            podpaski10 = 0;
            tampony10 = 0;
            wkladki10 = 0;
            podpaski11 = 0;
            tampony11 = 0;
            wkladki11 = 0;
            podpaski12 = 0;
            tampony12 = 0;
            wkladki12 = 0;

            x = length(data);
            for i = 1:1:x
                if data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if data.Month(j, 1) == 1
                            podpaski1 = podpaski1 + podpaski(j, 1);
                            tampony1 = tampony1 + tampony(j, 1);
                            wkladki1 = wkladki1 + wkladki(j, 1);
                        elseif data.Month(j, 1) == 2
                            podpaski2 = podpaski2 + podpaski(j, 1);
                            tampony2 = tampony2 + tampony(j, 1);
                            wkladki2 = wkladki2 + wkladki(j, 1);
                        elseif data.Month(j, 1) == 3
                            podpaski3 = podpaski2 + podpaski(j, 1);
                            tampony3 = tampony3 + tampony(j, 1);
                            wkladki3 = wkladki3 + wkladki(j, 1);
                        elseif data.Month(j, 1) == 4
                            podpaski4 = podpaski4 + podpaski(j, 1);
                            tampony4 = tampony4 + tampony(j, 1);
                            wkladki4 = wkladki4 + wkladki(j, 1);
                        elseif data.Month(j, 1) == 5
                            podpaski5 = podpaski5 + podpaski(j, 1);
                            tampony5 = tampony5 + tampony(j, 1);
                            wkladki5 = wkladki5 + wkladki(j, 1);
                        elseif data.Month(j, 1) == 6
                            podpaski6 = podpaski6 + podpaski(j, 1);
                            tampony6 = tampony6 + tampony(j, 1);
                            wkladki6 = wkladki6 + wkladki(j, 1);
                        elseif data.Month(j, 1) == 7
                            podpaski7 = podpaski7 + podpaski(j, 1);
                            tampony7 = tampony7 + tampony(j, 1);
                            wkladki7 = wkladki7 + wkladki(j, 1);
                        elseif data.Month(j, 1) == 8
                            podpaski8 = podpaski8 + podpaski(j, 1);
                            tampony8 = tampony8 + tampony(j, 1);
                            wkladki8 = wkladki8 + wkladki(j, 1);
                        elseif data.Month(j, 1) == 9
                            podpaski9 = podpaski9 + podpaski(j, 1);
                            tampony9 = tampony9 + tampony(j, 1);
                            wkladki9 = wkladki9 + wkladki(j, 1);
                        elseif data.Month(j, 1) == 10
                            podpaski10 = podpaski10 + podpaski(j, 1);
                            tampony10 = tampony10 + tampony(j, 1);
                            wkladki10 = wkladki10 + wkladki(j, 1);
                        elseif data.Month(j, 1) == 11
                            podpaski11 = podpaski11 + podpaski(j, 1);
                            tampony11 = tampony11 + tampony(j, 1);
                            wkladki11 = wkladki11 + wkladki(j, 1);
                        elseif data.Month(j, 1) == 12
                            podpaski12 = podpaski12 + podpaski(j, 1);
                            tampony12 = tampony12 + tampony(j, 1);
                            wkladki12 = wkladki12 + wkladki(j, 1);
                        end
                    end
                    break
                end
            end

            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [podpaski1 podpaski2 podpaski3 podpaski4 podpaski5 podpaski6 podpaski7 podpaski8 podpaski9 podpaski10 podpaski11 podpaski12; tampony1 tampony2 tampony3 tampony4 tampony5 tampony6 tampony7 tampony8 tampony9 tampony10 tampony11 tampony12; wkladki1 wkladki2 wkladki3 wkladki4 wkladki5 wkladki6 wkladki7 wkladki8 wkladki9 wkladki10 wkladki11 wkladki12];

            bar(app.UIAxes4, x, y)

        end

        % Button pushed function: OKButton_3
        function OKLeki(app, event)
            app.UITable.Data = {};
            app.UITable2.Data = {};
            mies = app.MiesicDropDown_3.Value;
            rok = app.RokDropDown_3.Value;
            leki = readtable('lek.txt');
            leki = sortrows(leki, 'data');
            x = length(leki.data);

            if mies == '1'
                mies = 1;
            elseif mies == '2'
                mies = 2;
            elseif mies == '3'
                mies = 3;
            elseif mies == '4'
                mies = 4;
            elseif mies == '5'
                mies = 5;
            elseif mies == '6'
                mies = 6;
            elseif mies == '7'
                mies = 7;
            elseif mies == '8'
                mies = 8;
            elseif mies == '9'
                mies = 9;
            elseif mies == "10"
                mies = 10;
            elseif mies == "11"
                mies = 11;
            else mies == "12"
                mies = 12;
            end

            if rok == "2022"
                rok = 2022;
            elseif rok == "2021"
                rok = 2021;
            end

            for i = 1:1:x
                if leki.data.Month(i, 1) == mies
                    if leki.data.Year(i, 1) == rok
                        if leki.ilosc_leku(i, 1) ~= 0
                            oldData = app.UITable.Data;
                            newRow = leki(i, :);
                            newData = [oldData; newRow];
                            app.UITable.Data = newData;
                        end
                    end
                end
            end
            
            lekarstwa = readtable('leki.txt');
            lekarstwa = string(lekarstwa.addItems);
            nazwa = string(leki.Var2);
            x = length(lekarstwa);
            y = length(nazwa);
            suma = 0;

            for i = 1:1:x
                for j = 1:1:y
                    if lekarstwa(i, 1) == nazwa(j, 1)
                        if leki.ilosc_leku(j, 1) ~= 0
                            suma = suma + leki.ilosc_leku(j, 1);
                        end
                    end
                end
                oldData = app.UITable2.Data;
                newRow = table(lekarstwa(i, 1), suma);
                newData = [oldData; newRow];
                app.UITable2.Data = newData;
                suma = 0;
            end
            
%             newTable = [nazwa, leki.ilosc_leku];
%             sumaApap = 0;
%             for i = 1:1:x
%                 ilosc = double(newTable(i, 2));
%                 if leki.data.Year(i, 1) == rok
%                     if newTable(i, 1) == 'apap'
%                         sumaApap = sumaApap + ilosc;
%                     end
%                 end
%             end

        end

        % Button pushed function: OKButton_4
        function OKObjawy(app, event)
            app.UITable3.Data = {};
            cla(app.UIAxes5);
            mies = app.MiesicDropDown_4.Value;
            rok = app.RokDropDown_4.Value;
            objawy = readtable('objawy.txt');
            objawy = sortrows(objawy, 'data');
            x = length(objawy.data);

            if mies == '1'
                mies = 1;
            elseif mies == '2'
                mies = 2;
            elseif mies == '3'
                mies = 3;
            elseif mies == '4'
                mies = 4;
            elseif mies == '5'
                mies = 5;
            elseif mies == '6'
                mies = 6;
            elseif mies == '7'
                mies = 7;
            elseif mies == '8'
                mies = 8;
            elseif mies == '9'
                mies = 9;
            elseif mies == "10"
                mies = 10;
            elseif mies == "11"
                mies = 11;
            else mies == "12"
                mies = 12;
            end

            if rok == "2022"
                rok = 2022;
            elseif rok == "2021"
                rok = 2021;
            end


            for i = 1:1:x
                if objawy.data.Month(i, 1) == mies
                    if objawy.data.Year(i, 1) == rok
                        oldData = app.UITable3.Data;
                        newRow = objawy(i, :);
                        newData = [oldData; newRow];
                        app.UITable3.Data = newData;
                    end
                end
            end


            bolBrzucha1 = 0;
            bolGlowy1 = 0;
            bolwKrzyzu1 = 0;
            biegunka1 = 0;
            zaparcia1 = 0;
            wzdecia1 = 0;
            bolPiersi1 = 0;
            zmianySkorne1 = 0;
            rozdraznienie1 = 0;
            smutek1 = 0;
            wahaniaNastroju1 = 0;
            zmeczenie1 = 0;
            koncentracja1 = 0;
            samoocena1 = 0;
            spadekLibido1 = 0;
            brakApetytu1 = 0;
            nadmiernyApetyt1 = 0;
            sennosc1 = 0;
            bolBrzucha2 = 0;
            bolGlowy2 = 0;
            bolwKrzyzu2 = 0;
            biegunka2 = 0;
            zaparcia2 = 0;
            wzdecia2 = 0;
            bolPiersi2 = 0;
            zmianySkorne2 = 0;
            rozdraznienie2 = 0;
            smutek2 = 0;
            wahaniaNastroju2 = 0;
            zmeczenie2 = 0;
            koncentracja2 = 0;
            samoocena2 = 0;
            spadekLibido2 = 0;
            brakApetytu2 = 0;
            nadmiernyApetyt2 = 0;
            sennosc2 = 0;
            bolBrzucha3 = 0;
            bolGlowy3 = 0;
            bolwKrzyzu3 = 0;
            biegunka3 = 0;
            zaparcia3 = 0;
            wzdecia3 = 0;
            bolPiersi3 = 0;
            zmianySkorne3 = 0;
            rozdraznienie3 = 0;
            smutek3 = 0;
            wahaniaNastroju3 = 0;
            zmeczenie3 = 0;
            koncentracja3 = 0;
            samoocena3 = 0;
            spadekLibido3 = 0;
            brakApetytu3 = 0;
            nadmiernyApetyt3 = 0;
            sennosc3 = 0;
            bolBrzucha4 = 0;
            bolGlowy4 = 0;
            bolwKrzyzu4 = 0;
            biegunka4 = 0;
            zaparcia4 = 0;
            wzdecia4 = 0;
            bolPiersi4 = 0;
            zmianySkorne4 = 0;
            rozdraznienie4 = 0;
            smutek4 = 0;
            wahaniaNastroju4 = 0;
            zmeczenie4 = 0;
            koncentracja4 = 0;
            samoocena4 = 0;
            spadekLibido4 = 0;
            brakApetytu4 = 0;
            nadmiernyApetyt4 = 0;
            sennosc4 = 0;
            bolBrzucha5 = 0;
            bolGlowy5 = 0;
            bolwKrzyzu5 = 0;
            biegunka5 = 0;
            zaparcia5 = 0;
            wzdecia5 = 0;
            bolPiersi5 = 0;
            zmianySkorne5 = 0;
            rozdraznienie5 = 0;
            smutek5 = 0;
            wahaniaNastroju5 = 0;
            zmeczenie5 = 0;
            koncentracja5 = 0;
            samoocena5 = 0;
            spadekLibido5 = 0;
            brakApetytu5 = 0;
            nadmiernyApetyt5 = 0;
            sennosc5 = 0;
            bolBrzucha6 = 0;
            bolGlowy6 = 0;
            bolwKrzyzu6 = 0;
            biegunka6 = 0;
            zaparcia6 = 0;
            wzdecia6 = 0;
            bolPiersi6 = 0;
            zmianySkorne6 = 0;
            rozdraznienie6 = 0;
            smutek6 = 0;
            wahaniaNastroju6 = 0;
            zmeczenie6 = 0;
            koncentracja6 = 0;
            samoocena6 = 0;
            spadekLibido6 = 0;
            brakApetytu6 = 0;
            nadmiernyApetyt6 = 0;
            sennosc6 = 0;
            bolBrzucha7 = 0;
            bolGlowy7 = 0;
            bolwKrzyzu7 = 0;
            biegunka7 = 0;
            zaparcia7 = 0;
            wzdecia7 = 0;
            bolPiersi7 = 0;
            zmianySkorne7 = 0;
            rozdraznienie7 = 0;
            smutek7 = 0;
            wahaniaNastroju7 = 0;
            zmeczenie7 = 0;
            koncentracja7 = 0;
            samoocena7 = 0;
            spadekLibido7 = 0;
            brakApetytu7 = 0;
            nadmiernyApetyt7 = 0;
            sennosc7 = 0;
            bolBrzucha8 = 0;
            bolGlowy8 = 0;
            bolwKrzyzu8 = 0;
            biegunka8 = 0;
            zaparcia8 = 0;
            wzdecia8 = 0;
            bolPiersi8 = 0;
            zmianySkorne8 = 0;
            rozdraznienie8 = 0;
            smutek8 = 0;
            wahaniaNastroju8 = 0;
            zmeczenie8 = 0;
            koncentracja8 = 0;
            samoocena8 = 0;
            spadekLibido8 = 0;
            brakApetytu8 = 0;
            nadmiernyApetyt8 = 0;
            sennosc8 = 0;
            bolBrzucha9 = 0;
            bolGlowy9 = 0;
            bolwKrzyzu9 = 0;
            biegunka9 = 0;
            zaparcia9 = 0;
            wzdecia9 = 0;
            bolPiersi9 = 0;
            zmianySkorne9 = 0;
            rozdraznienie9 = 0;
            smutek9 = 0;
            wahaniaNastroju9 = 0;
            zmeczenie9 = 0;
            koncentracja9 = 0;
            samoocena9 = 0;
            spadekLibido9 = 0;
            brakApetytu9 = 0;
            nadmiernyApetyt9 = 0;
            sennosc9 = 0;
            bolBrzucha10 = 0;
            bolGlowy10 = 0;
            bolwKrzyzu10 = 0;
            biegunka10 = 0;
            zaparcia10 = 0;
            wzdecia10 = 0;
            bolPiersi10 = 0;
            zmianySkorne10 = 0;
            rozdraznienie10 = 0;
            smutek10 = 0;
            wahaniaNastroju10 = 0;
            zmeczenie10 = 0;
            koncentracja10 = 0;
            samoocena10 = 0;
            spadekLibido10 = 0;
            brakApetytu10 = 0;
            nadmiernyApetyt10 = 0;
            sennosc10 = 0;
            bolBrzucha11 = 0;
            bolGlowy11 = 0;
            bolwKrzyzu11 = 0;
            biegunka11 = 0;
            zaparcia11 = 0;
            wzdecia11 = 0;
            bolPiersi11 = 0;
            zmianySkorne11 = 0;
            rozdraznienie11 = 0;
            smutek11 = 0;
            wahaniaNastroju11 = 0;
            zmeczenie11 = 0;
            koncentracja11 = 0;
            samoocena11 = 0;
            spadekLibido11 = 0;
            brakApetytu11 = 0;
            nadmiernyApetyt11 = 0;
            sennosc11 = 0;
            bolBrzucha12 = 0;
            bolGlowy12 = 0;
            bolwKrzyzu12 = 0;
            biegunka12 = 0;
            zaparcia12 = 0;
            wzdecia12 = 0;
            bolPiersi12 = 0;
            zmianySkorne12 = 0;
            rozdraznienie12 = 0;
            smutek12 = 0;
            wahaniaNastroju12 = 0;
            zmeczenie12 = 0;
            koncentracja12 = 0;
            samoocena12 = 0;
            spadekLibido12 = 0;
            brakApetytu12 = 0;
            nadmiernyApetyt12 = 0;
            sennosc12 = 0;

            input = string(app.DolegliwoDropDown.Value);

            if input == 'ból brzucha'
                input = 1;
            end
            if input == 'ból głowy'
                input = 2;
            end
            if input == 'ból w krzyżu'
                input = 3;
            end
            if input == 'biegunka'
                input = 4;
            end
            if input == 'zaparcia'
                input = 5;
            end
            if input == 'wzdęcia'
                input = 6;
            end
            if input == 'ból piersi'
                input = 7;
            end
            if input == 'zmiany skórne'
                input = 8;
            end
            if input == 'rozdrażnienie'
                input = 9;
            end
            if input == 'smutek'
                input = 10;
            end
            if input == 'wahania nastroju'
                input = 11;
            end
            if input == 'zmęczenie'
                input = 12;
            end
            if input == 'problemy z koncentracją'
                input = 13;
            end
            if input == 'spadek samooceny'
                input = 14;
            end
            if input == 'spadek libido'
                input = 15;
            end
            if input == 'brak apetytu'
                input = 16;
            end
            if input == 'nadmierny apetyt'
                input = 17;
            end
            if input == 'senność'
                input = 18;
            end

        if input == 1
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            bolBrzucha1 = bolBrzucha1 + objawy.bolBrzucha(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            bolBrzucha2 = bolBrzucha2 + objawy.bolBrzucha(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            bolBrzucha3 = bolBrzucha3 + objawy.bolBrzucha(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            bolBrzucha4 = bolBrzucha4 + objawy.bolBrzucha(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            bolBrzucha5 = bolBrzucha5 + objawy.bolBrzucha(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            bolBrzucha6 = bolBrzucha6 + objawy.bolBrzucha(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            bolBrzucha7 = bolBrzucha7 + objawy.bolBrzucha(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            bolBrzucha8 = bolBrzucha8 + objawy.bolBrzucha(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            bolBrzucha9 = bolBrzucha9 + objawy.bolBrzucha(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            bolBrzucha10 = bolBrzucha10 + objawy.bolBrzucha(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            bolBrzucha11 = bolBrzucha11 + objawy.bolBrzucha(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            bolBrzucha12 = bolBrzucha12 + objawy.bolBrzucha(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [bolBrzucha1 bolBrzucha2 bolBrzucha3 bolBrzucha4 bolBrzucha5 bolBrzucha6 bolBrzucha7 bolBrzucha8 bolBrzucha9 bolBrzucha10 bolBrzucha11 bolBrzucha12];
            bar(app.UIAxes5, x, y)

        elseif input == 2
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            bolGlowy1 = bolGlowy1 + objawy.bolGlowy(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            bolGlowy2 = bolGlowy2 + objawy.bolGlowy(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            bolGlowy3 = bolGlowy3 + objawy.bolGlowy(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            bolGlowy4 = bolGlowy4 + objawy.bolGlowy(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            bolGlowy5 = bolGlowy5 + objawy.bolGlowy(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            bolGlowy6 = bolGlowy6 + objawy.bolGlowy(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            bolGlowy7 = bolGlowy7 + objawy.bolGlowy(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            bolGlowy8 = bolGlowy8 + objawy.bolGlowy(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            bolGlowy9 = bolGlowy9 + objawy.bolGlowy(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            bolGlowy10 = bolGlowy10 + objawy.bolGlowy(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            bolGlowy11 = bolGlowy11 + objawy.bolGlowy(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            bolGlowy12 = bolGlowy12 + objawy.bolGlowy(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [bolGlowy1 bolGlowy2 bolGlowy3 bolGlowy4 bolGlowy5 bolGlowy6 bolGlowy7 bolGlowy8 bolGlowy9 bolGlowy10 bolGlowy11 bolGlowy12];
            bar(app.UIAxes5, x, y)

            elseif input == 3
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            bolwKrzyzu1 = bolwKrzyzu1 + objawy.bolwKrzyzu(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            bolwKrzyzu2 = bolwKrzyzu2 + objawy.bolwKrzyzu(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            bolwKrzyzu3 = bolwKrzyzu3 + objawy.bolwKrzyzu(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            bolwKrzyzu4 = bolwKrzyzu4 + objawy.bolwKrzyzu(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            bolwKrzyzu5 = bolwKrzyzu5 + objawy.bolwKrzyzu(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            bolwKrzyzu6 = bolwKrzyzu6 + objawy.bolwKrzyzu(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            bolwKrzyzu7 = bolwKrzyzu7 + objawy.bolwKrzyzu(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            bolwKrzyzu8 = bolwKrzyzu8 + objawy.bolwKrzyzu(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            bolwKrzyzu9 = bolwKrzyzu9 + objawy.bolwKrzyzu(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            bolwKrzyzu10 = bolwKrzyzu10 + objawy.bolwKrzyzu(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            bolwKrzyzu11 = bolwKrzyzu11 + objawy.bolwKrzyzu(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            bolwKrzyzu12 = bolwKrzyzu12 + objawy.bolwKrzyzu(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [bolwKrzyzu1 bolwKrzyzu2 bolwKrzyzu3 bolwKrzyzu4 bolwKrzyzu5 bolwKrzyzu6 bolwKrzyzu7 bolwKrzyzu8 bolwKrzyzu9 bolwKrzyzu10 bolwKrzyzu11 bolwKrzyzu12];
            bar(app.UIAxes5, x, y)

            elseif input == 4
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            biegunka1 = biegunka1 + objawy.biegunka(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            biegunka2 = biegunka2 + objawy.biegunka(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            biegunka3 = biegunka3 + objawy.biegunka(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            biegunka4 = biegunka4 + objawy.biegunka(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            biegunka5 = biegunka5 + objawy.biegunka(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            biegunka6 = biegunka6 + objawy.biegunka(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            biegunka7 = biegunka7 + objawy.biegunka(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            biegunka8 = biegunka8 + objawy.biegunka(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            biegunka9 = biegunka9 + objawy.biegunka(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            biegunka10 = biegunka10 + objawy.biegunka(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            biegunka11 = biegunka11 + objawy.biegunka(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            biegunka12 = biegunka12 + objawy.biegunka(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [biegunka1 biegunka2 biegunka3 biegunka4 biegunka5 biegunka6 biegunka7 biegunka8 biegunka9 biegunka10 biegunka11 biegunka12];
            bar(app.UIAxes5, x, y)

            elseif input == 5
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            zaparcia1 = zaparcia1 + objawy.zaparcia(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            zaparcia2 = zaparcia2 + objawy.zaparcia(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            zaparcia3 = zaparcia3 + objawy.bolwKrzyzu(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            zaparcia4 = zaparcia4 + objawy.zaparcia(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            zaparcia5 = zaparcia5 + objawy.zaparcia(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            zaparcia6 = zaparcia6 + objawy.zaparcia(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            zaparcia7 = zaparcia7 + objawy.zaparcia(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            zaparcia8 = zaparcia8 + objawy.zaparcia(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            zaparcia9 = zaparcia9 + objawy.zaparcia(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            zaparcia10 = zaparcia10 + objawy.zaparcia(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            zaparcia11 = zaparcia11 + objawy.zaparcia(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            zaparcia12 = zaparcia12 + objawy.zaparcia(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [zaparcia1 zaparcia2 zaparcia3 zaparcia4 zaparcia5 zaparcia6 zaparcia7 zaparcia8 zaparcia9 zaparcia10 zaparcia11 zaparcia12];
            bar(app.UIAxes5, x, y)

            elseif input == 6
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            wzdecia1 = wzdecia1 + objawy.wzdecia(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            wzdecia2 = wzdecia2 + objawy.wzdecia(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            wzdecia3 = wzdecia3 + objawy.wzdecia(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            wzdecia4 = wzdecia4 + objawy.wzdecia(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            wzdecia5 = wzdecia5 + objawy.wzdecia(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            wzdecia6 = wzdecia6 + objawy.wzdecia(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            wzdecia7 = wzdecia7 + objawy.wzdecia(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            wzdecia8 = wzdecia8 + objawy.wzdecia(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            wzdecia9 = wzdecia9 + objawy.wzdecia(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            wzdecia10 = wzdecia10 + objawy.wzdecia(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            wzdecia11 = wzdecia11 + objawy.wzdecia(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            wzdecia12 = wzdecia12 + objawy.wzdecia(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [wzdecia1 wzdecia2 wzdecia3 wzdecia4 wzdecia5 wzdecia6 wzdecia7 wzdecia8 wzdecia9 wzdecia10 wzdecia11 wzdecia12];
            bar(app.UIAxes5, x, y)

            elseif input == 7
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            bolPiersi1 = bolPiersi1 + objawy.bolPiersi(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            bolPiersi2 = bolPiersi2 + objawy.bolPiersi(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            bolPiersi3 = bolPiersi3 + objawy.bolPiersi(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            bolPiersi4 = bolPiersi4 + objawy.bolPiersi(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            bolPiersi5 = bolPiersi5 + objawy.bolPiersi(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            bolPiersi6 = bolPiersi6 + objawy.bolPiersi(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            bolPiersi7 = bolPiersi7 + objawy.bolPiersi(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            bolPiersi8 = bolPiersi8 + objawy.bolPiersi(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            bolPiersi9 = bolPiersi9 + objawy.bolPiersi(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            bolPiersi10 = bolPiersi10 + objawy.bolPiersi(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            bolPiersi11 = bolPiersi11 + objawy.bolPiersi(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            bolPiersi12 = bolPiersi12 + objawy.bolPiersi(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [bolPiersi1 bolPiersi2 bolPiersi3 bolPiersi4 bolPiersi5 bolPiersi6 bolPiersi7 bolPiersi8 bolPiersi9 bolPiersi10 bolPiersi11 bolPiersi12];
            bar(app.UIAxes5, x, y)

            elseif input == 8
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            zmianySkorne1 = zmianySkorne1 + objawy.zmianySkorne(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            zmianySkorne2 = zmianySkorne2 + objawy.zmianySkorne(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            zmianySkorne3 = zmianySkorne3 + objawy.zmianySkorne(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            zmianySkorne4 = zmianySkorne4 + objawy.zmianySkorne(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            zmianySkorne5 = zmianySkorne5 + objawy.zmianySkorne(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            zmianySkorne6 = zmianySkorne6 + objawy.zmianySkorne(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            zmianySkorne7 = zmianySkorne7 + objawy.zmianySkorne(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            zmianySkorne8 = zmianySkorne8 + objawy.zmianySkorne(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            zmianySkorne9 = zmianySkorne9 + objawy.zmianySkorne(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            zmianySkorne10 = zmianySkorne10 + objawy.zmianySkorne(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            zmianySkorne11 = zmianySkorne11 + objawy.zmianySkorne(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            zmianySkorne12 = zmianySkorne12 + objawy.zmianySkorne(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [zmianySkorne1 zmianySkorne2 zmianySkorne3 zmianySkorne4 zmianySkorne5 zmianySkorne6 zmianySkorne7 zmianySkorne8 zmianySkorne9 zmianySkorne10 zmianySkorne11 zmianySkorne12];
            bar(app.UIAxes5, x, y)
            
            elseif input == 9
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            rozdraznienie1 = rozdraznienie1 + objawy.rozdraznienie(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            rozdraznienie2 = rozdraznienie2 + objawy.rozdraznienie(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            rozdraznienie3 = rozdraznienie3 + objawy.rozdraznienie(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            rozdraznienie4 = rozdraznienie4 + objawy.rozdraznienie(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            rozdraznienie5 = rozdraznienie5 + objawy.rozdraznienie(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            rozdraznienie6 = rozdraznienie6 + objawy.rozdraznienie(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            rozdraznienie7 = rozdraznienie7 + objawy.rozdraznienie(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            rozdraznienie8 = rozdraznienie8 + objawy.rozdraznienie(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            rozdraznienie9 = rozdraznienie9 + objawy.rozdraznienie(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            rozdraznienie10 = rozdraznienie10 + objawy.rozdraznienie(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            rozdraznienie11 = rozdraznienie11 + objawy.rozdraznienie(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            rozdraznienie12 = rozdraznienie12 + objawy.rozdraznienie(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [rozdraznienie1 rozdraznienie2 rozdraznienie3 rozdraznienie4 rozdraznienie5 rozdraznienie6 rozdraznienie7 rozdraznienie8 rozdraznienie9 rozdraznienie10 rozdraznienie11 rozdraznienie12];
            bar(app.UIAxes5, x, y)

            elseif input == 10
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            smutek1 = smutek1 + objawy.smutek(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            smutek2 = smutek2 + objawy.smutek(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            smutek3 = smutek3 + objawy.smutek(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            smutek4 = smutek4 + objawy.smutek(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            smutek5 = smutek5 + objawy.smutek(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            smutek6 = smutek6 + objawy.smutek(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            smutek7 = smutek7 + objawy.smutek(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            smutek8 = smutek8 + objawy.smutek(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            smutek9 = smutek9 + objawy.smutek(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            smutek10 = smutek10 + objawy.smutek(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            smutek11 = smutek11 + objawy.smutek(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            smutek12 = smutek12 + objawy.smutek(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [smutek1 smutek2 smutek3 smutek4 smutek5 smutek6 smutek7 smutek8 smutek9 smutek10 smutek11 smutek12];
            bar(app.UIAxes5, x, y)

            elseif input == 11
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            wahaniaNastroju1 = wahaniaNastroju1 + objawy.wahaniaNastroju(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            wahaniaNastroju2 = wahaniaNastroju2 + objawy.wahaniaNastroju(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            wahaniaNastroju3 = wahaniaNastroju3 + objawy.wahaniaNastroju(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            wahaniaNastroju4 = wahaniaNastroju4 + objawy.wahaniaNastroju(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            wahaniaNastroju5 = wahaniaNastroju5 + objawy.wahaniaNastroju(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            wahaniaNastroju6 = wahaniaNastroju6 + objawy.wahaniaNastroju(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            wahaniaNastroju7 = wahaniaNastroju7 + objawy.wahaniaNastroju(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            wahaniaNastroju8 = wahaniaNastroju8 + objawy.wahaniaNastroju(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            wahaniaNastroju9 = wahaniaNastroju9 + objawy.wahaniaNastroju(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            wahaniaNastroju10 = wahaniaNastroju10 + objawy.wahaniaNastroju(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            wahaniaNastroju11 = wahaniaNastroju11 + objawy.wahaniaNastroju(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            wahaniaNastroju12 = wahaniaNastroju12 + objawy.wahaniaNastroju(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [wahaniaNastroju1 wahaniaNastroju2 wahaniaNastroju3 wahaniaNastroju4 wahaniaNastroju5 wahaniaNastroju6 wahaniaNastroju7 wahaniaNastroju8 wahaniaNastroju9 wahaniaNastroju10 wahaniaNastroju11 wahaniaNastroju12];
            bar(app.UIAxes5, x, y)

            elseif input == 12
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            zmeczenie1 = zmeczenie1 + objawy.zmeczenie(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            zmeczenie2 = zmeczenie2 + objawy.zmeczenie(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            zmeczenie3 = zmeczenie3 + objawy.zmeczenie(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            zmeczenie4 = zmeczenie4 + objawy.zmeczenie(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            zmeczenie5 = zmeczenie5 + objawy.zmeczenie(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            zmeczenie6 = zmeczenie6 + objawy.zmeczenie(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            zmeczenie7 = zmeczenie7 + objawy.zmeczenie(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            zmeczenie8 = zmeczenie8 + objawy.zmeczenie(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            zmeczenie9 = zmeczenie9 + objawy.zmeczenie(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            zmeczenie10 = zmeczenie10 + objawy.zmeczenie(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            zmeczenie11 = zmeczenie11 + objawy.zmeczenie(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            zmeczenie12 = zmeczenie12 + objawy.zmeczenie(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [zmeczenie1 zmeczenie2 zmeczenie3 zmeczenie4 zmeczenie5 zmeczenie6 zmeczenie7 zmeczenie8 zmeczenie9 zmeczenie10 zmeczenie11 zmeczenie12];
            bar(app.UIAxes5, x, y)

            elseif input == 13
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            koncentracja1 = koncentracja1 + objawy.koncentracja(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            koncentracja2 = koncentracja2 + objawy.koncentracja(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            koncentracja3 = koncentracja3 + objawy.koncentracja(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            koncentracja4 = koncentracja4 + objawy.koncentracja(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            koncentracja5 = koncentracja5 + objawy.koncentracja(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            koncentracja6 = koncentracja6 + objawy.koncentracja(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            koncentracja7 = koncentracja7 + objawy.koncentracja(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            koncentracja8 = koncentracja8 + objawy.koncentracja(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            koncentracja9 = koncentracja9 + objawy.koncentracja(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            koncentracja10 = koncentracja10 + objawy.koncentracja(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            koncentracja11 = koncentracja11 + objawy.koncentracja(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            koncentracja12 = koncentracja12 + objawy.koncentracja(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [koncentracja1 koncentracja2 koncentracja3 koncentracja4 koncentracja5 koncentracja6 koncentracja7 koncentracja8 koncentracja9 koncentracja10 koncentracja11 koncentracja12];
            bar(app.UIAxes5, x, y)

            elseif input == 14
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            samoocena1 = samoocena1 + objawy.samoocena(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            samoocena2 = samoocena2 + objawy.samoocena(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            samoocena3 = samoocena3 + objawy.samoocena(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            samoocena4 = samoocena4 + objawy.samoocena(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            samoocena5 = samoocena5 + objawy.samoocena(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            samoocena6 = samoocena6 + objawy.samoocena(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            samoocena7 = samoocena7 + objawy.samoocena(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            samoocena8 = samoocena8 + objawy.samoocena(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            samoocena9 = samoocena9 + objawy.samoocena(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            samoocena10 = samoocena10 + objawy.samoocena(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            samoocena11 = samoocena11 + objawy.samoocena(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            samoocena12 = samoocena12 + objawy.samoocena(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [samoocena1 samoocena2 samoocena3 samoocena4 samoocena5 samoocena6 samoocena7 samoocena8 samoocena9 samoocena10 samoocena11 samoocena12];
            bar(app.UIAxes5, x, y)

            elseif input == 15
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            spadekLibido1 = spadekLibido1 + objawy.spadekLibido(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            spadekLibido2 = spadekLibido2 + objawy.spadekLibido(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            spadekLibido3 = spadekLibido3 + objawy.spadekLibido(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            spadekLibido4 = spadekLibido4 + objawy.spadekLibido(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            spadekLibido5 = spadekLibido5 + objawy.spadekLibido(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            spadekLibido6 = spadekLibido6 + objawy.spadekLibido(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            spadekLibido7 = spadekLibido7 + objawy.spadekLibido(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            spadekLibido8 = spadekLibido8 + objawy.spadekLibido(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            spadekLibido9 = spadekLibido9 + objawy.spadekLibido(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            spadekLibido10 = spadekLibido10 + objawy.spadekLibido(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            spadekLibido11 = spadekLibido11 + objawy.spadekLibido(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            spadekLibido12 = spadekLibido12 + objawy.spadekLibido(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [spadekLibido1 spadekLibido2 spadekLibido3 spadekLibido4 spadekLibido5 spadekLibido6 spadekLibido7 spadekLibido8 spadekLibido9 spadekLibido10 spadekLibido11 spadekLibido12];
            bar(app.UIAxes5, x, y)

            elseif input == 16
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            brakApetytu1 = brakApetytu1 + objawy.brakApetytu(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            brakApetytu2 = brakApetytu2 + objawy.brakApetytu(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            brakApetytu3 = brakApetytu3 + objawy.brakApetytu(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            brakApetytu4 = brakApetytu4 + objawy.brakApetytu(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            brakApetytu5 = brakApetytu5 + objawy.brakApetytu(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            brakApetytu6 = brakApetytu6 + objawy.brakApetytu(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            brakApetytu7 = brakApetytu7 + objawy.brakApetytu(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            brakApetytu8 = brakApetytu8 + objawy.brakApetytu(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            brakApetytu9 = brakApetytu9 + objawy.brakApetytu(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            brakApetytu10 = brakApetytu10 + objawy.brakApetytu(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            brakApetytu11 = brakApetytu11 + objawy.brakApetytu(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            brakApetytu12 = brakApetytu12 + objawy.brakApetytu(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [brakApetytu1 brakApetytu2 brakApetytu3 brakApetytu4 brakApetytu5 brakApetytu6 brakApetytu7 brakApetytu8 brakApetytu9 brakApetytu10 brakApetytu11 brakApetytu12];
            bar(app.UIAxes5, x, y)

            elseif input == 17
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            nadmiernyApetyt1 = nadmiernyApetyt1 + objawy.nadmiernyApetyt(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            nadmiernyApetyt2 = nadmiernyApetyt2 + objawy.nadmiernyApetyt(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            nadmiernyApetyt3 = nadmiernyApetyt3 + objawy.nadmiernyApetyt(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            nadmiernyApetyt4 = nadmiernyApetyt4 + objawy.nadmiernyApetyt(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            nadmiernyApetyt5 = nadmiernyApetyt5 + objawy.nadmiernyApetyt(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            nadmiernyApetyt6 = nadmiernyApetyt6 + objawy.nadmiernyApetyt(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            nadmiernyApetyt7 = nadmiernyApetyt7 + objawy.nadmiernyApetyt(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            nadmiernyApetyt8 = nadmiernyApetyt8 + objawy.nadmiernyApetyt(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            nadmiernyApetyt9 = nadmiernyApetyt9 + objawy.nadmiernyApetyt(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            nadmiernyApetyt10 = nadmiernyApetyt10 + objawy.nadmiernyApetyt(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            nadmiernyApetyt11 = nadmiernyApetyt11 + objawy.nadmiernyApetyt(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            nadmiernyApetyt12 = nadmiernyApetyt12 + objawy.nadmiernyApetyt(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [nadmiernyApetyt1 nadmiernyApetyt2 nadmiernyApetyt3 nadmiernyApetyt4 nadmiernyApetyt5 nadmiernyApetyt6 nadmiernyApetyt7 nadmiernyApetyt8 nadmiernyApetyt9 nadmiernyApetyt10 nadmiernyApetyt11 nadmiernyApetyt12];
            bar(app.UIAxes5, x, y)

            elseif input == 18
            for i = 1:1:x
                if objawy.data.Year(i, 1) == rok
                    new1 = i;
                    if x > i + 365
                        new2 = new1+365;
                    else
                        new2 = x;
                    end
                    for j = new1:1:new2
                        if objawy.data.Month(j, 1) == 1
                            sennosc1 = sennosc1 + objawy.sennosc(j, 1);
                        elseif objawy.data.Month(j, 1) == 2
                            sennosc2 = sennosc2 + objawy.sennosc(j, 1);
                        elseif objawy.data.Month(j, 1) == 3
                            sennosc3 = sennosc3 + objawy.sennosc(j, 1);
                        elseif objawy.data.Month(j, 1) == 4
                            sennosc4 = sennosc4 + objawy.sennosc(j, 1);
                        elseif objawy.data.Month(j, 1) == 5
                            sennosc5 = sennosc5 + objawy.sennosc(j, 1);
                        elseif objawy.data.Month(j, 1) == 6
                            sennosc6 = sennosc6 + objawy.sennosc(j, 1);
                        elseif objawy.data.Month(j, 1) == 7
                            sennosc7 = sennosc7 + objawy.sennosc(j, 1);
                        elseif objawy.data.Month(j, 1) == 8
                            sennosc8 = sennosc8 + objawy.sennosc(j, 1);
                        elseif objawy.data.Month(j, 1) == 9
                            sennosc9 = sennosc9 + objawy.sennosc(j, 1);
                        elseif objawy.data.Month(j, 1) == 10
                            sennosc10 = sennosc10 + objawy.sennosc(j, 1);
                        elseif objawy.data.Month(j, 1) == 11
                            sennosc11 = sennosc11 + objawy.sennosc(j, 1);
                        elseif objawy.data.Month(j, 1) == 12
                            sennosc12 = sennosc12 + objawy.sennosc(j, 1);
                        end
                    end
                    break
                end
            end
            x = categorical({'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            x = reordercats(x, {'styczeń', 'luty', 'marzec', 'kwiecień', 'maj', 'czerwiec', 'lipiec', 'sierpień', 'wrzesień', 'październik', 'listopad', 'grudzień'});
            y = [sennosc1 sennosc2 sennosc3 sennosc4 sennosc5 sennosc6 sennosc7 sennosc8 sennosc9 sennosc10 sennosc11 sennosc12];
            bar(app.UIAxes5, x, y)

        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WprowadzaniedanychTab = uitab(app.TabGroup);
            app.WprowadzaniedanychTab.Title = 'Wprowadzanie danych';
            app.DataDatePickerLabel = uilabel(app.WprowadzaniedanychTab);
            app.DataDatePickerLabel.Text = 'Data';
            app.DataDatePicker = uidatepicker(app.WprowadzaniedanychTab);
            app.DataDatePicker.DisplayFormat = 'dd.MM.uuuu';
            app.DataDatePicker.Value = datetime([2021 10 19]);
            app.PocztekmiesiczkiCheckBox = uicheckbox(app.WprowadzaniedanychTab);
            app.PocztekmiesiczkiCheckBox.Text = 'Początek miesiączki';
            app.KoniecmiesiczkiCheckBox = uicheckbox(app.WprowadzaniedanychTab);
            app.KoniecmiesiczkiCheckBox.Text = 'Koniec miesiączki';
            app.ZuycierodkwhigienicznychLabel = uilabel(app.WprowadzaniedanychTab);
            app.ZuycierodkwhigienicznychLabel.Text = 'Zużycie środków higienicznych';
            app.IntensywnokrwawieniaSliderLabel = uilabel(app.WprowadzaniedanychTab);
            app.IntensywnokrwawieniaSliderLabel.Text = 'Intensywność krwawienia';
            app.IntensywnokrwawieniaSlider = uislider(app.WprowadzaniedanychTab);
            app.IntensywnokrwawieniaSlider.Limits = [0 5];
            app.IntensywnokrwawieniaSlider.MajorTicks = [0 1 2 3 4 5];
            app.IntensywnokrwawieniaSlider.ValueChangedFcn = createCallbackFcn(app, @SliderInt, true);
            app.IntensywnokrwawieniaSlider.MinorTicks = [0 1 2 3 4 5];
            app.PodpaskiEditFieldLabel = uilabel(app.WprowadzaniedanychTab);
            app.PodpaskiEditFieldLabel.Text = 'Podpaski';
            app.PodpaskiEditField = uieditfield(app.WprowadzaniedanychTab, 'numeric');
            app.PodpaskiEditField.Limits = [0 Inf];
            app.PodpaskiEditField.ValueDisplayFormat = '%.0f';
            app.TamponyEditFieldLabel = uilabel(app.WprowadzaniedanychTab);
            app.TamponyEditFieldLabel.Text = 'Tampony';
            app.TamponyEditField = uieditfield(app.WprowadzaniedanychTab, 'numeric');
            app.TamponyEditField.Limits = [0 Inf];
            app.TamponyEditField.ValueDisplayFormat = '%.0f';
            app.WkadkiEditFieldLabel = uilabel(app.WprowadzaniedanychTab);
            app.WkadkiEditFieldLabel.Text = 'Wkładki';
            app.WkadkiEditField = uieditfield(app.WprowadzaniedanychTab, 'numeric');
            app.WkadkiEditField.Limits = [0 Inf];
            app.WkadkiEditField.ValueDisplayFormat = '%.0f';
            app.DodajButton = uibutton(app.WprowadzaniedanychTab, 'push');
            app.DodajButton.ButtonPushedFcn = createCallbackFcn(app, @Dalej1, true);
            app.DodajButton.Text = 'Dodaj';
            app.OwulacjaCheckBox = uicheckbox(app.WprowadzaniedanychTab);
            app.OwulacjaCheckBox.Text = 'Owulacja';
            app.PrzyjtelekiLabel = uilabel(app.WprowadzaniedanychTab);
            app.PrzyjtelekiLabel.Text = 'Przyjęte leki';
            app.IloprzyjtegolekuEditFieldLabel = uilabel(app.WprowadzaniedanychTab);
            app.IloprzyjtegolekuEditFieldLabel.Text = 'Ilość przyjętego leku';
            app.IloprzyjtegolekuEditField = uieditfield(app.WprowadzaniedanychTab, 'numeric');
            app.IloprzyjtegolekuEditField.Limits = [0 Inf];
            app.IloprzyjtegolekuEditField.ValueDisplayFormat = '%.0f';
            app.DodajlekButton = uibutton(app.WprowadzaniedanychTab, 'push');
            app.DodajlekButton.ButtonPushedFcn = createCallbackFcn(app, @DodajLek, true);
            app.DodajlekButton.Text = 'Dodaj lek';
            app.NazwalekuDropDownLabel = uilabel(app.WprowadzaniedanychTab);
            app.NazwalekuDropDownLabel.Text = 'Nazwa leku';
            app.NazwalekuDropDown = uidropdown(app.WprowadzaniedanychTab);
            app.NazwalekuDropDown.Items = {};
            app.NazwalekuDropDown.Editable = 'on';
            app.NazwalekuDropDown.Value = {};
            app.NowylekEditFieldLabel = uilabel(app.WprowadzaniedanychTab);
            app.NowylekEditFieldLabel.Text = 'Nowy lek';
            app.NowylekEditField = uieditfield(app.WprowadzaniedanychTab, 'text');
            app.ObjawyTab = uitab(app.TabGroup);
            app.ObjawyTab.Title = 'Objawy';
            app.BlegowyCheckBox_2 = uicheckbox(app.ObjawyTab);
            app.BlegowyCheckBox_2.Text = 'Bóle głowy';
            app.BlewkrzyuCheckBox_2 = uicheckbox(app.ObjawyTab);
            app.BlewkrzyuCheckBox_2.Text = 'Bóle w krzyżu';
            app.BiegunkiCheckBox_2 = uicheckbox(app.ObjawyTab);
            app.BiegunkiCheckBox_2.Text = 'Biegunki';
            app.OBJAWYLabel = uilabel(app.ObjawyTab);
            app.OBJAWYLabel.Text = 'OBJAWY';
            app.ZaparciaCheckBox_2 = uicheckbox(app.ObjawyTab);
            app.ZaparciaCheckBox_2.Text = 'Zaparcia';
            app.WzdciaCheckBox_2 = uicheckbox(app.ObjawyTab);
            app.WzdciaCheckBox_2.Text = 'Wzdęcia';
            app.BlepodbrzuszaCheckBox = uicheckbox(app.ObjawyTab);
            app.BlepodbrzuszaCheckBox.Text = 'Bóle podbrzusza';
            app.PowikszenieitkliwopiersiCheckBox_2 = uicheckbox(app.ObjawyTab);
            app.PowikszenieitkliwopiersiCheckBox_2.Text = 'Powiększenie i tkliwość piersi';
            app.ZmianyskrneCheckBox_2 = uicheckbox(app.ObjawyTab);
            app.ZmianyskrneCheckBox_2.Text = 'Zmiany skórne';
            app.RozdranienieCheckBox_2 = uicheckbox(app.ObjawyTab);
            app.RozdranienieCheckBox_2.Text = 'Rozdrażnienie';
            app.SmutekCheckBox_2 = uicheckbox(app.ObjawyTab);
            app.SmutekCheckBox_2.Text = 'Smutek';
            app.WahanianastrojuCheckBox = uicheckbox(app.ObjawyTab);
            app.WahanianastrojuCheckBox.Text = 'Wahania nastroju';
            app.ZmczenieCheckBox = uicheckbox(app.ObjawyTab);
            app.ZmczenieCheckBox.Text = 'Zmęczenie';
            app.ProblemyzkoncentracjCheckBox_2 = uicheckbox(app.ObjawyTab);
            app.ProblemyzkoncentracjCheckBox_2.Text = 'Problemy z koncentracją';
            app.SpadeksamoocenyCheckBox_2 = uicheckbox(app.ObjawyTab);
            app.SpadeksamoocenyCheckBox_2.Text = 'Spadek samooceny';
            app.SpadeklibidoCheckBox_2 = uicheckbox(app.ObjawyTab);
            app.SpadeklibidoCheckBox_2.Text = 'Spadek libido';
            app.BrakapetytuCheckBox = uicheckbox(app.ObjawyTab);
            app.BrakapetytuCheckBox.Text = 'Brak apetytu';
            app.NadmiernyapetytCheckBox = uicheckbox(app.ObjawyTab);
            app.NadmiernyapetytCheckBox.Text = 'Nadmierny apetyt';
            app.SennoCheckBox = uicheckbox(app.ObjawyTab);
            app.SennoCheckBox.Text = 'Senność';
            app.DodajButton_2 = uibutton(app.ObjawyTab, 'push');
            app.DodajButton_2.ButtonPushedFcn = createCallbackFcn(app, @Dodaj2, true);
            app.DodajButton_2.Text = 'Dodaj';
            app.CyklTab = uitab(app.TabGroup);
            app.CyklTab.Title = 'Cykl';
            app.UIAxes = uiaxes(app.CyklTab);
            title(app.UIAxes, 'Miesiąc')
            xlabel(app.UIAxes, 'data')
            ylabel(app.UIAxes, 'intensywność okresu')
            app.UIAxes2 = uiaxes(app.CyklTab);
            title(app.UIAxes2, 'Rok')
            xlabel(app.UIAxes2, 'miesiąc')
            ylabel(app.UIAxes2, 'intensywność okresu')
            app.MiesicDropDownLabel = uilabel(app.CyklTab);
            app.MiesicDropDownLabel.Text = 'Miesiąc';
            app.MiesicDropDown = uidropdown(app.CyklTab);
            app.MiesicDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.MiesicDropDown.Value = '1';
            app.RokDropDownLabel = uilabel(app.CyklTab);
            app.RokDropDownLabel.Text = 'Rok';
            app.RokDropDown = uidropdown(app.CyklTab);
            app.RokDropDown.Items = {'2022', '2021'};
            app.RokDropDown.Value = '2021';
            app.OKButton = uibutton(app.CyklTab, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKcykl, true);
            app.OKButton.Text = 'OK';
            app.Label = uilabel(app.CyklTab);
            app.Label.Text = '';
            app.redniadugookresuLabel = uilabel(app.CyklTab);
            app.redniadugookresuLabel.Text = 'Średnia długość okresu';
            app.redniadugocykluLabel = uilabel(app.CyklTab);
            app.redniadugocykluLabel.Text = 'Średnia długość cyklu';
            app.Label_2 = uilabel(app.CyklTab);
            app.Label_2.Text = '';
            app.rodkihigieniczneTab = uitab(app.TabGroup);
            app.rodkihigieniczneTab.Title = 'Środki higieniczne';
            app.UIAxes3 = uiaxes(app.rodkihigieniczneTab);
            title(app.UIAxes3, 'Miesiąc')
            xlabel(app.UIAxes3, 'środki higieniczne')
            ylabel(app.UIAxes3, 'liczba')
            app.UIAxes4 = uiaxes(app.rodkihigieniczneTab);
            title(app.UIAxes4, 'Rok')
            ylabel(app.UIAxes4, 'liczba')
            app.MiesicDropDown_2Label = uilabel(app.rodkihigieniczneTab);
            app.MiesicDropDown_2Label.Text = 'Miesiąc';
            app.MiesicDropDown_2 = uidropdown(app.rodkihigieniczneTab);
            app.MiesicDropDown_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.MiesicDropDown_2.Value = '1';
            app.RokDropDown_2Label = uilabel(app.rodkihigieniczneTab);
            app.RokDropDown_2Label.Text = 'Rok';
            app.RokDropDown_2 = uidropdown(app.rodkihigieniczneTab);
            app.RokDropDown_2.Items = {'2021', '2022'};
            app.RokDropDown_2.Value = '2021';
            app.OKButton_2 = uibutton(app.rodkihigieniczneTab, 'push');
            app.OKButton_2.ButtonPushedFcn = createCallbackFcn(app, @HigOK, true);
            app.OKButton_2.Text = 'OK';
            app.LekiTab = uitab(app.TabGroup);
            app.LekiTab.Title = 'Leki';
            app.UITable = uitable(app.LekiTab);
            app.UITable.ColumnName = {'data'; 'nazwa leku'; 'ilość'};
            app.UITable.RowName = {};
            app.OKButton_3 = uibutton(app.LekiTab, 'push');
            app.OKButton_3.ButtonPushedFcn = createCallbackFcn(app, @OKLeki, true);
            app.OKButton_3.Text = 'OK';
            app.MiesicDropDown_3Label = uilabel(app.LekiTab);
            app.MiesicDropDown_3Label.Text = 'Miesiąc';
            app.MiesicDropDown_3 = uidropdown(app.LekiTab);
            app.MiesicDropDown_3.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.MiesicDropDown_3.Value = '1';
            app.RokDropDown_3Label = uilabel(app.LekiTab);
            app.RokDropDown_3Label.Text = 'Rok';
            app.RokDropDown_3 = uidropdown(app.LekiTab);
            app.RokDropDown_3.Items = {'2021', '2022'};
            app.RokDropDown_3.Value = '2021';
            app.UITable2 = uitable(app.LekiTab);
            app.UITable2.ColumnName = {'nazwa leku'; 'suma roczna'};
            app.UITable2.RowName = {};
            app.ObjawyTab_2 = uitab(app.TabGroup);
            app.ObjawyTab_2.Title = 'Objawy';
            app.UIAxes5 = uiaxes(app.ObjawyTab_2);
            ylabel(app.UIAxes5, 'liczba')
            app.MiesicDropDown_4Label = uilabel(app.ObjawyTab_2);
            app.MiesicDropDown_4Label.Text = 'Miesiąc';
            app.MiesicDropDown_4 = uidropdown(app.ObjawyTab_2);
            app.MiesicDropDown_4.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.MiesicDropDown_4.Value = '1';
            app.RokDropDown_4Label = uilabel(app.ObjawyTab_2);
            app.RokDropDown_4Label.Text = 'Rok';
            app.RokDropDown_4 = uidropdown(app.ObjawyTab_2);
            app.RokDropDown_4.Items = {'2021', '2022'};
            app.RokDropDown_4.Value = '2021';
            app.OKButton_4 = uibutton(app.ObjawyTab_2, 'push');
            app.OKButton_4.ButtonPushedFcn = createCallbackFcn(app, @OKObjawy, true);
            app.OKButton_4.Text = 'OK';
            app.DolegliwoDropDownLabel = uilabel(app.ObjawyTab_2);
            app.DolegliwoDropDownLabel.Text = 'Dolegliwość';
            app.DolegliwoDropDown = uidropdown(app.ObjawyTab_2);
            app.DolegliwoDropDown.Items = {'ból brzucha', 'ból głowy', 'ból w krzyżu', 'biegunka', 'zaparcia', 'wzdęcia', 'ból piersi', 'zmiany skórne', 'rozdrażnienie', 'smutek', 'wahania nastroju', 'zmęczenie', 'problemy z koncentracją', 'spadek samooceny', 'spadek libido', 'brak apetytu', 'nadmierny apetyt', 'senność'};
            app.DolegliwoDropDown.Value = 'ból brzucha';
            app.UITable3 = uitable(app.ObjawyTab_2);
            app.UITable3.ColumnName = {'data'; 'ból brzucha'; 'ból głowy'; 'ból w krzyżu'; 'biegunka'; 'zaparcia'; 'wzdęcia'; 'ból piersi'; 'zmiany skórne'; 'rozdrażnienie'; 'smutek'; 'wahania nastroju'; 'zmęczenie'; 'problemy z koncentracją'; 'spadek samooceny'; 'spadek libido'; 'brak apetytu'; 'nadmierny apetyt'; 'senność'};
            app.UITable3.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end